classdef dCoupSTplus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DiffusionSlider           matlab.ui.control.Slider
        DiffusionSliderLabel      matlab.ui.control.Label
        chan3CheckBox             matlab.ui.control.CheckBox
        chan2CheckBox             matlab.ui.control.CheckBox
        chan1CheckBox             matlab.ui.control.CheckBox
        AllCheckBox               matlab.ui.control.CheckBox
        SaveBatchButton           matlab.ui.control.Button
        SaveLUTButton             matlab.ui.control.Button
        ProxySizeEditField        matlab.ui.control.NumericEditField
        ProxySizeEditFieldLabel   matlab.ui.control.Label
        BitDepthEditField         matlab.ui.control.NumericEditField
        BitDepthEditFieldLabel    matlab.ui.control.Label
        CropWorkingButton         matlab.ui.control.Button
        WorkingOETFDropDown       matlab.ui.control.DropDown
        WorkingSpaceDropDown      matlab.ui.control.DropDown
        workFile                  matlab.ui.control.DropDown
        refFile                   matlab.ui.control.DropDown
        sourceFile                matlab.ui.control.DropDown
        WorkingFolderButton       matlab.ui.control.Button
        TargetFolderButton        matlab.ui.control.Button
        SourceFolderButton        matlab.ui.control.Button
        SetDefaultButton          matlab.ui.control.Button
        ComputeButton             matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        MomentDropDown            matlab.ui.control.DropDown
        MomentDropDownLabel       matlab.ui.control.Label
        RESULTLabel               matlab.ui.control.Label
        TARGETLabel               matlab.ui.control.Label
        SOURCELabel               matlab.ui.control.Label
        SaveResultButton_2        matlab.ui.control.Button
        outIm                     matlab.ui.control.Image
        refIm                     matlab.ui.control.Image
        sourceIm                  matlab.ui.control.Image
        UseSourceCheckBox         matlab.ui.control.CheckBox
        LUTSizeEditField          matlab.ui.control.NumericEditField
        LUTSizeEditFieldLabel     matlab.ui.control.Label
        CropReferenceButton       matlab.ui.control.Button
        CropSourceButton          matlab.ui.control.Button
        OutputEOTFDropDown        matlab.ui.control.DropDown
        OutputEOTFDropDownLabel   matlab.ui.control.Label
        ReferenceOETFDropDown     matlab.ui.control.DropDown
        SourceOETFDropDown        matlab.ui.control.DropDown
        OutputSpaceDropDown       matlab.ui.control.DropDown
        OutputSpaceDropDownLabel  matlab.ui.control.Label
        ReferenceSpaceDropDown    matlab.ui.control.DropDown
        SourceSpaceDropDown       matlab.ui.control.DropDown
        outHist                   matlab.ui.control.UIAxes
        refHist                   matlab.ui.control.UIAxes
        sourceHist                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        params = struct(); % Description
        W = zeros(1,1,1); % Description
        R = zeros(1,1,1); % Description
        Rdisplay = zeros(1,1,1);
        S = zeros(1,1,1); % Description
        Sdisplay = zeros(1,1,1);
        O = zeros(1,1,1);
        Rpath = cell(1);
        Rdir = cell(1);
        Rfile = '';
        Wpath = cell(1);
        Wdir = cell(1);
        Wfile = '';
        Spath = cell(1);
        Sdir = cell(1);
        Sfile = '';
        oPath = '';
        outDir = '';
        lutDir = '';
        batchDir = '';
        batchPath = '';
        prevDir = '';
        lutPath = '';
        resizeF = 0.5
        defaultMom = [1,1,1;1,1,1,;1,0,0;1,0,0;0,0,0];
        sourceCropped = 0;
        refCropped = 0;
        Nmom = 0;
        
    end
    
    methods (Access = private)
        
        function runNgun(app)

            if app.UseSourceCheckBox.Value == 1
                app.W = app.S;
            end
            tic;
            [app.O,moments,kernel,lut] = mainControlST(app.W,app.S,app.R,app.params);
            toc
            app.outIm.ImageSource = app.O;
            app.params.moments = moments;
            app.params.luts = lut;
            %app.params.luts = luts;
            app.params.kernel = kernel;
            
            % insert plotting function
            %[sITPvect,rITPvect,oITPvect,sRGBvect,rRGBvect,oRGBvect] = vecter(app.S,app.R,app.O,app.params);
            %app.sourceHist = pcshow(sITPvect,sRGBvect);
            %plot(app.sourceHist,axS);
            %app.refHist = pcshow(rITPvect,rRGBvect);
            %plot(app.refHist,axR);
            %app.outHist = pcshow(real(oITPvect),oRGBvect);
            %plot(app.outHist,axO);
            
            histS = cell(3,2);
            histR = cell(3,2);
            histO = cell(3,2);
            for i = 1:3
                [yS,xS] = imhist(app.S(:,:,i));
                [yR,xR] = imhist(app.R(:,:,i));
                [yO,xO] = imhist(app.O(:,:,i));
                histS{i,2} = yS;
                histS{i,1} = xS;
                histR{i,2} = yR;
                histR{i,1} = xR;
                histO{i,2} = yO;
                histO{i,1} = xO;
            end
            
            plot(app.sourceHist,histS{1,1},histS{1,2},'Red',histS{2,1},histS{2,2},'Green',histS{3,1},histS{3,2},'Blue');
            plot(app.refHist,histR{1,1},histR{1,2},'Red',histR{2,1},histR{2,2},'Green',histR{3,1},histR{3,2},'Blue');
            plot(app.outHist,histO{1,1},histO{1,2},'Red',histO{2,1},histO{2,2},'Green',histO{3,1},histO{3,2},'Blue');

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % init
            app.params.moments = 0;
            app.params.luts = 0;
            app.params.lutSize = app.LUTSizeEditField.Value;
            app.params.moms = app.defaultMom;
            app.params.depth = 8;
            app.params.resizeF = app.ProxySizeEditField.Value;
            app.Nmom = 1;
            % set image parameters
            app.params.sCSin = {app.SourceSpaceDropDown.Value}; 
            app.params.sGamIn = str2double(app.SourceOETFDropDown.Value); 
            app.params.rCSin = {app.ReferenceSpaceDropDown.Value};
            app.params.rGamIn = str2double(app.ReferenceOETFDropDown.Value);
            app.params.csInt = {'IPT'};%{app.ApplicationSpaceDropDown.Value};
            app.params.gamInt = 1;%str2double(app.ApplicationGammaDropDown.Value);
            app.params.csOut = {app.OutputSpaceDropDown.Value};
            app.params.gamOut = str2double(app.OutputEOTFDropDown.Value);
            app.Sdir = 'images';
            app.Spath = 'images/source.jpg';
            app.Wdir = 'images';
            app.Wpath = 'images/working.jpg'; 
            app.Rdir = 'images';
            app.Rpath = 'images/target.jpg'; 
            app.oPath = 'images/out.jpg';
            app.batchDir = 'images';
            MomentDropDownValueChanged(app);
            app.chan1CheckBox.Text = 'I';%app.ApplicationSpaceDropDown.Value(1);
            app.chan2CheckBox.Text = 'P';%app.ApplicationSpaceDropDown.Value(2);
            app.chan3CheckBox.Text = 'T';%app.ApplicationSpaceDropDown.Value(3);
            app.chan1CheckBox.Value = app.params.moms(app.Nmom,1);
            app.chan2CheckBox.Value = app.params.moms(app.Nmom,2);
            app.chan3CheckBox.Value = app.params.moms(app.Nmom,3);
            
            if sum(app.params.moms(app.Nmom,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            
        end

        % Callback function
        function SourcePathEditFieldValueChanged(app, event)
%             app.Spath = app.SourcePathEditField.Value;
%             app.S = imread(app.Spath);
%             app.sourceIm.ImageSource = app.S;       
        end

        % Callback function
        function WorkingPathEditFieldValueChanged(app, event)
%             useSource = app.UseSourceCheckBox.Value;
%             if useSource == 1
%                 app.Wpath = app.Spath;
%             else
%                 app.Wpath = app.Wpath;
%             end
%             app.W = imread(app.Wpath);      
        end

        % Value changed function: UseSourceCheckBox
        function UseSourceCheckBoxValueChanged(app, event)
            app.W = imread(app.Wpath);  
        end

        % Button pushed function: CropSourceButton
        function CropSourceButtonPushed(app, event)
            app.S = imread(app.Spath);
            app.S = imcrop(app.S);
            app.Sdisplay = double(app.S)./((2^app.params.depth)-1);
            app.sourceIm.ImageSource = app.Sdisplay;
            app.sourceCropped = 1;
            app.params.moments = 0;
        end

        % Button pushed function: CropReferenceButton
        function CropReferenceButtonPushed(app, event)
            app.R = imread(app.Rpath);
            app.R = imcrop(app.R);
            app.Rdisplay = double(app.R)./((2^app.params.depth)-1);
            app.refIm.ImageSource = app.Rdisplay;
            app.refCropped = 1;
            app.params.moments = 0;
        end

        % Callback function
        function ImageClicked(app, event)
            % don't really need it
        end

        % Callback function
        function ReferencePathEditFieldValueChanged(app, event)
%             app.Rpath = app.ReferencePathEditField.Value;
%             app.R = imread(app.Rpath);
%             app.refIm.ImageSource = app.R;            
        end

        % Callback function
        function sourceImImageClicked(app, event)
            % don't really need this
        end

        % Value changed function: SourceSpaceDropDown
        function SourceSpaceDropDownValueChanged(app, event)
            app.params.sCSin = {app.SourceSpaceDropDown.Value};
            app.params.moments = 0;
        end

        % Value changed function: SourceOETFDropDown
        function SourceOETFDropDownValueChanged(app, event)
            app.params.sGamIn = str2double(app.SourceOETFDropDown.Value);
            app.params.moments = 0;
        end

        % Value changed function: ReferenceSpaceDropDown
        function ReferenceSpaceDropDownValueChanged(app, event)
            app.params.rCSin = {app.ReferenceSpaceDropDown.Value};
            app.params.moments = 0;
        end

        % Value changed function: ReferenceOETFDropDown
        function ReferenceOETFDropDownValueChanged(app, event)
            app.params.rGamIn = str2double(app.ReferenceOETFDropDown.Value);
            app.params.moments = 0;
        end

        % Callback function
        function ApplicationSpaceDropDownValueChanged(app, event)
            app.params.csInt = {app.ApplicationSpaceDropDown.Value};
            app.chan1CheckBox.Text = app.ApplicationSpaceDropDown.Value(1);
            app.chan2CheckBox.Text = app.ApplicationSpaceDropDown.Value(2);
            app.chan3CheckBox.Text = app.ApplicationSpaceDropDown.Value(3);
            app.params.moments = 0;
        end

        % Callback function
        function ApplicationGammaDropDownValueChanged(app, event)
            app.params.gamInt = str2double(app.ApplicationGammaDropDown.Value);
        end

        % Value changed function: OutputSpaceDropDown
        function OutputSpaceDropDownValueChanged(app, event)
            app.params.csOut = {app.OutputSpaceDropDown.Value};
        end

        % Value changed function: OutputEOTFDropDown
        function OutputEOTFDropDownValueChanged(app, event)
            app.params.gamOut = str2double(app.OutputEOTFDropDown.Value);
        end

        % Value changed function: LUTSizeEditField
        function LUTSizeEditFieldValueChanged(app, event)
            app.params.lutSize = str2double(app.LUTSizeEditField.Value);
            app.params.luts = 0;
        end

        % Callback function
        function OutputPathEditFieldValueChanged(app, event)
            % don't need this
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.params.moms = app.defaultMom;

            app.chan1CheckBox.Value = app.params.moms(app.Nmom,1);
            app.chan2CheckBox.Value = app.params.moms(app.Nmom,2);
            app.chan3CheckBox.Value = app.params.moms(app.Nmom,3);

            % make all check box 0 if not all boxes checked
            if sum(app.params.moms(app.Nmom,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            
            app.R = imread(app.Rpath);
            app.refIm.ImageSource = app.R; 
            app.S = imread(app.Spath);
            app.sourceIm.ImageSource = app.S; 
            runNgun(app);
            app.params.moments = 0;
            
        end

        % Value changed function: MomentDropDown
        function MomentDropDownValueChanged(app, event)
            whichMoment = app.MomentDropDown.Value;%app.MomentDropDown.ItemsData;
            switch whichMoment
                case 'Mean'
                    Nmom = 1;
                case 'Variance'  
                    Nmom = 2;
                case 'Skew'
                    Nmom = 3;
                case 'Kurtosis'
                    Nmom = 4;
                    %case 'Diffusion' % moved to slider ..
                %    Nmom = 5;
            end
            app.chan1CheckBox.Value = app.params.moms(Nmom,1);
            app.chan2CheckBox.Value = app.params.moms(Nmom,2);
            app.chan3CheckBox.Value = app.params.moms(Nmom,3);
            % make all check box 0 if not all boxes checked
            %app.params.moms(Nmom,:)
            if sum(app.params.moms(Nmom,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            app.Nmom = Nmom;
        end

        % Callback function
        function Chan1SliderValueChanged(app, event)
% removed from UI
%             whichMoment = app.Nmom;
%             app.params.moms(whichMoment,1) = app.Chan1Slider.Value;
            
        end

        % Callback function
        function Chan2SliderValueChanged(app, event)
% removed from UI
%             whichMoment = app.Nmom;
%             app.params.moms(whichMoment,2) = app.Chan2Slider.Value;
            
        end

        % Callback function
        function Chan3SliderValueChanged(app, event)
% removed from UI
%             whichMoment = app.Nmom;
%             app.params.moms(whichMoment,3) = app.Chan3Slider.Value;      
        end

        % Button pushed function: SaveResultButton_2
        function SaveResultButton_2Pushed(app, event)
            [name,path] = uiputfile(app.oPath);
            app.oPath = [path,name];
            app.params.resizeF = 1;
            [app.O,moments,kernel,lut] = mainControlST(app.W,app.S,app.R,app.params);
            imwrite(app.O,app.oPath);
            pack app.oPath
            if app.refCropped
                imwrite(app.R,[app.oPath(1:end-4),'_rCrop',app.oPath(end-4:end)]);
                pack [app.oPath(1:end-4),'_rCrop',app.oPath(end-4:end)]
            end
            if app.sourceCropped
                imwrite(app.S,[app.oPath(1:end-4),'_sCrop',app.oPath(end-4:end)]);  
                pack [app.oPath(1:end-4),'_sCrop',app.oPath(end-4:end)]
            end
            app.params.moments = moments;
            app.params.kernel = kernel;
            app.params.luts = lut;
            params = app.params;
            save([path,'params.mat'],'params');
            app.params.resizeF = app.resizeF;
            pack 
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            runNgun(app);
        end

        % Button pushed function: SourceFolderButton
        function SourceFolderButtonPushed(app, event)
            app.prevDir = app.Sdir;
            app.Sdir = uigetdir(app.Sdir,'Source Folder');
            if isnumeric(app.Sdir)
                app.Sdir = app.prevDir;
            end
            directy = dir(app.Sdir);
            fileList = natsortfiles({directy.name});
            app.sourceFile.Items = fileList;
        end

        % Button pushed function: TargetFolderButton
        function TargetFolderButtonPushed(app, event)
            app.prevDir = app.Rdir;
            app.Rdir = uigetdir(app.Rdir,'Target Folder');
            if isnumeric(app.Rdir)
                app.Rdir = app.prevDir;
            end
            directy = dir(app.Rdir);
            fileList = natsortfiles({directy.name});
            app.refFile.Items = fileList;
        end

        % Button pushed function: WorkingFolderButton
        function WorkingFolderButtonPushed(app, event)
            app.prevDir = app.Wdir;
            app.Wdir = uigetdir(app.Wdir,'Working Folder');
            if isnumeric(app.Wdir)
                app.Wdir = app.prevDir;
            end
            directy = dir(app.Wdir);
            fileList = natsortfiles({directy.name});
            app.workFile.Items = fileList;
        end

        % Value changed function: sourceFile
        function sourceFileValueChanged(app, event)
            directy = dir(app.Sdir);
            fileList = natsortfiles({directy.name});
            app.sourceFile.Items = fileList;            
            app.Sfile = app.sourceFile.Value;
            app.Spath = [app.Sdir,'/',app.Sfile];
            app.S = imread(app.Spath);
            app.Sdisplay = double(app.S)./((2^app.params.depth)-1);
            app.sourceIm.ImageSource = app.Sdisplay;
            if app.UseSourceCheckBox.Value == 1
                app.W = app.S;
            end 
            app.params.moments = 0;
        end

        % Button pushed function: CropWorkingButton
        function CropWorkingButtonPushed(app, event)
            app.W = imread(app.Wpath);
            app.W = imcrop(app.W);
            app.params.moments = 0;
        end

        % Value changed function: BitDepthEditField
        function BitDepthEditFieldValueChanged(app, event)
            app.params.depth = app.BitDepthEditField.Value;
            app.params.moments = 0;
        end

        % Value changed function: ProxySizeEditField
        function ProxySizeEditFieldValueChanged(app, event)
            app.resizeF = app.ProxySizeEditField.Value;
            app.params.resizeF = app.resizeF;
            %app.params.moments = 0;
        end

        % Button pushed function: SetDefaultButton
        function SetDefaultButtonPushed(app, event)
            app.defaultMom = app.params.moms;
        end

        % Button pushed function: SaveLUTButton
        function SaveLUTButtonPushed(app, event)
            [name,path] = uiputfile('lut.cube');
            app.lutPath = [path,name];
            [app.O,~,~,lut] = mainControlST(app.W,app.S,app.R,app.params);
                % print LUT
            fileID = fopen(app.lutPath,'w');
            cubeFormat = ['LUT_3D_SIZE ',num2str(app.params.lutSize),'\n \n'];
            fprintf(fileID,cubeFormat);
            fprintf(fileID,'%1.4f %1.4f %1.4f\n',lut');
            pack app.lutPath
        end

        % Button pushed function: SaveBatchButton
        function SaveBatchButtonPushed(app, event)
            app.prevDir = app.batchDir;
            app.batchDir = uigetdir(app.batchDir,'Batch Directory');
            if isnumeric(app.batchDir)
                app.batchDir = app.prevDir;
            end
            app.outDir = uigetdir(app.oPath);
            if isnumeric(app.outDir)
                app.outDir = app.oPath;
            end
            directy = dir(app.batchDir);
            fileList = natsortfiles({directy.name});
            for i = 1:length(fileList)
                try
                    app.batchPath = [app.batchDir,'/',fileList{i}];
                    app.W = imread(app.batchPath);
                    [app.O,~,~] = mainControlST(app.W,app.S,app.R,app.params);
                    imwrite(app.O,[app.outDir,'/',fileList{i}]); 
                    pack [app.outDir,'/',fileList{i}];    
                catch
                    warning([fileList{i},' is not a valid image file. moving on ..'])
                end
            end
        end

        % Value changed function: refFile
        function refFileValueChanged(app, event)
            directy = dir(app.Rdir);
            fileList = natsortfiles({directy.name});
            app.refFile.Items = fileList;
            app.Rfile = app.refFile.Value;
            app.Rpath = [app.Rdir,'/',app.Rfile];
            app.R = imread(app.Rpath);
            app.Rdisplay = double(app.R)./((2^app.params.depth)-1);
            app.refIm.ImageSource = app.Rdisplay;
            app.params.moments = 0;
        end

        % Value changed function: workFile
        function workFileValueChanged(app, event)
            directy = dir(app.Wdir);
            fileList = natsortfiles({directy.name});
            app.workFile.Items = fileList;
            app.Wfile = app.workFile.Value;
            app.Wpath = [app.Wdir,'/',app.Wfile];
            app.W = imread(app.Wpath);
            % app.params.moments = 0; %previous transforms can be reused
        end

        % Value changed function: chan3CheckBox
        function chan3CheckBoxValueChanged(app, event)
            whichMoment = app.Nmom;
            app.params.moms(whichMoment,3) = app.chan3CheckBox.Value;
            % set following moments to 0
            if app.chan3CheckBox.Value == 0 && whichMoment < 4                 
                for i = 1:(4-whichMoment)
                    app.params.moms(whichMoment+i,3) = app.chan3CheckBox.Value;
                end
            elseif app.chan1CheckBox.Value == 1 && whichMoment > 1 
                for i = 1:whichMoment-1
                    app.params.moms(whichMoment-i,3) = app.chan1CheckBox.Value;
                end
            end
            % make all check box 0 if not all boxes checked
            if sum(app.params.moms(whichMoment,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            app.params.moments = 0;
        end

        % Value changed function: chan2CheckBox
        function chan2CheckBoxValueChanged(app, event)
            whichMoment = app.Nmom;
            app.params.moms(whichMoment,2) = app.chan2CheckBox.Value;
            if app.chan2CheckBox.Value == 0 && whichMoment < 4                 
                for i = 1:(4-whichMoment)
                    app.params.moms(whichMoment+i,2) = app.chan2CheckBox.Value;
                end
            elseif app.chan1CheckBox.Value == 1 && whichMoment > 1 
                for i = 1:whichMoment-1
                    app.params.moms(whichMoment-i,2) = app.chan1CheckBox.Value;
                end
            end   
            % make all check box 0 if not all boxes checked
            if sum(app.params.moms(app.Nmom,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            app.params.moments = 0;
        end

        % Value changed function: chan1CheckBox
        function chan1CheckBoxValueChanged(app, event)
            whichMoment = app.Nmom;
            app.params.moms(whichMoment,1) = app.chan1CheckBox.Value;
            if app.chan1CheckBox.Value == 0 && whichMoment < 4                 
                for i = 1:(4-whichMoment)
                    app.params.moms(whichMoment+i,1) = app.chan1CheckBox.Value;
                end
            elseif app.chan1CheckBox.Value == 1 && whichMoment > 1 
                for i = 1:whichMoment-1
                    app.params.moms(whichMoment-i,1) = app.chan1CheckBox.Value;
                end
            end
            % make all check box 0 if not all boxes checked
            if sum(app.params.moms(app.Nmom,:)) < 3
                app.AllCheckBox.Value = 0;
            else
                app.AllCheckBox.Value = 1;
            end
            app.params.moments = 0;
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            whichMoment = app.Nmom;
%             if app.AllCheckBox.Value == 1
                app.params.moms(whichMoment,1) = app.AllCheckBox.Value;
                app.params.moms(whichMoment,2) = app.AllCheckBox.Value;
                app.params.moms(whichMoment,3) = app.AllCheckBox.Value;
                app.chan1CheckBox.Value = app.AllCheckBox.Value;
                app.chan2CheckBox.Value = app.AllCheckBox.Value;
                app.chan3CheckBox.Value = app.AllCheckBox.Value;
%             end
            
            if app.AllCheckBox.Value == 0 && whichMoment < 4        
                for i = 1:(4-whichMoment)
                    app.params.moms(whichMoment+i,1) = app.chan1CheckBox.Value;
                    app.params.moms(whichMoment+i,2) = app.chan2CheckBox.Value;
                    app.params.moms(whichMoment+i,3) = app.chan3CheckBox.Value;
                end
            elseif app.chan1CheckBox.Value == 1 && whichMoment > 1 && whichMoment < 5
                for i = 1:whichMoment-1
                    app.params.moms(whichMoment-i,1) = app.chan1CheckBox.Value;
                    app.params.moms(whichMoment-i,2) = app.chan2CheckBox.Value;
                    app.params.moms(whichMoment-i,3) = app.chan3CheckBox.Value;
                end 
            end
            app.params.moments = 0;
        end

        % Value changed function: DiffusionSlider
        function DiffusionSliderValueChanged(app, event)
            value = app.DiffusionSlider.Value;
            app.params.moms(5,:) = [value,value,value];
            runNgun(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sourceHist = uiaxes(app.UIFigure);
            title(app.sourceHist, 'Source')
            zlabel(app.sourceHist, 'Z')
            app.sourceHist.XTick = [];
            app.sourceHist.YTick = [];
            app.refHist = uiaxes(app.UIFigure);
            title(app.refHist, 'Target')
            zlabel(app.refHist, 'Z')
            app.refHist.XTick = [];
            app.refHist.YTick = [];
            app.outHist = uiaxes(app.UIFigure);
            title(app.outHist, 'Result')
            zlabel(app.outHist, 'Z')
            app.outHist.XTick = [];
            app.outHist.YTick = [];
            app.SourceSpaceDropDown = uidropdown(app.UIFigure);
            app.SourceSpaceDropDown.Items = {'sRGB', 'BT.2020', 'ProPhoto', 'ARRI', ''};
            app.SourceSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceSpaceDropDownValueChanged, true);
            app.SourceSpaceDropDown.Value = 'sRGB';
            app.ReferenceSpaceDropDown = uidropdown(app.UIFigure);
            app.ReferenceSpaceDropDown.Items = {'sRGB', 'BT.2020', 'ProPhoto', 'ARRI'};
            app.ReferenceSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceSpaceDropDownValueChanged, true);
            app.ReferenceSpaceDropDown.Value = 'sRGB';
            app.OutputSpaceDropDownLabel = uilabel(app.UIFigure);
            app.OutputSpaceDropDownLabel.Text = 'Output Space';
            app.OutputSpaceDropDown = uidropdown(app.UIFigure);
            app.OutputSpaceDropDown.Items = {'sRGB', 'BT.2020', 'DCI.P3.D65', 'DCI.P3.D50'};
            app.OutputSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputSpaceDropDownValueChanged, true);
            app.OutputSpaceDropDown.Value = 'sRGB';
            app.SourceOETFDropDown = uidropdown(app.UIFigure);
            app.SourceOETFDropDown.Items = {'1', '2.2', '2.4'};
            app.SourceOETFDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceOETFDropDownValueChanged, true);
            app.SourceOETFDropDown.Value = '2.2';
            app.ReferenceOETFDropDown = uidropdown(app.UIFigure);
            app.ReferenceOETFDropDown.Items = {'1', '2.2', '2.4', ''};
            app.ReferenceOETFDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceOETFDropDownValueChanged, true);
            app.ReferenceOETFDropDown.Value = '2.2';
            app.OutputEOTFDropDownLabel = uilabel(app.UIFigure);
            app.OutputEOTFDropDownLabel.Text = 'Output EOTF';
            app.OutputEOTFDropDown = uidropdown(app.UIFigure);
            app.OutputEOTFDropDown.Items = {'2.2', '2.4'};
            app.OutputEOTFDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputEOTFDropDownValueChanged, true);
            app.OutputEOTFDropDown.Value = '2.2';
            app.CropSourceButton = uibutton(app.UIFigure, 'push');
            app.CropSourceButton.ButtonPushedFcn = createCallbackFcn(app, @CropSourceButtonPushed, true);
            app.CropSourceButton.Text = 'Crop Source';
            app.CropReferenceButton = uibutton(app.UIFigure, 'push');
            app.CropReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CropReferenceButtonPushed, true);
            app.CropReferenceButton.Text = 'Crop Reference';
            app.LUTSizeEditFieldLabel = uilabel(app.UIFigure);
            app.LUTSizeEditFieldLabel.Text = 'LUT Size';
            app.LUTSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LUTSizeEditField.ValueChangedFcn = createCallbackFcn(app, @LUTSizeEditFieldValueChanged, true);
            app.LUTSizeEditField.Value = 33;
            app.UseSourceCheckBox = uicheckbox(app.UIFigure);
            app.UseSourceCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSourceCheckBoxValueChanged, true);
            app.UseSourceCheckBox.Text = 'Use Source';
            app.UseSourceCheckBox.Value = true;
            app.sourceIm = uiimage(app.UIFigure);
            app.refIm = uiimage(app.UIFigure);
            app.outIm = uiimage(app.UIFigure);
            app.SaveResultButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveResultButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButton_2Pushed, true);
            app.SaveResultButton_2.Text = 'Save Result';
            app.SOURCELabel = uilabel(app.UIFigure);
            app.SOURCELabel.Text = 'SOURCE';
            app.TARGETLabel = uilabel(app.UIFigure);
            app.TARGETLabel.Text = 'TARGET';
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT';
            app.MomentDropDownLabel = uilabel(app.UIFigure);
            app.MomentDropDownLabel.Text = 'Moment';
            app.MomentDropDown = uidropdown(app.UIFigure);
            app.MomentDropDown.Items = {'Mean', 'Variance', 'Skew', 'Kurtosis'};
            app.MomentDropDown.ValueChangedFcn = createCallbackFcn(app, @MomentDropDownValueChanged, true);
            app.MomentDropDown.Value = 'Mean';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.SetDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultButtonPushed, true);
            app.SetDefaultButton.Text = 'Set Default';
            app.SourceFolderButton = uibutton(app.UIFigure, 'push');
            app.SourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SourceFolderButtonPushed, true);
            app.SourceFolderButton.Text = 'Source Folder';
            app.TargetFolderButton = uibutton(app.UIFigure, 'push');
            app.TargetFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TargetFolderButtonPushed, true);
            app.TargetFolderButton.Text = {'Target Folder'; ''};
            app.WorkingFolderButton = uibutton(app.UIFigure, 'push');
            app.WorkingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @WorkingFolderButtonPushed, true);
            app.WorkingFolderButton.Text = 'Working Folder';
            app.sourceFile = uidropdown(app.UIFigure);
            app.sourceFile.ValueChangedFcn = createCallbackFcn(app, @sourceFileValueChanged, true);
            app.refFile = uidropdown(app.UIFigure);
            app.refFile.ValueChangedFcn = createCallbackFcn(app, @refFileValueChanged, true);
            app.workFile = uidropdown(app.UIFigure);
            app.workFile.ValueChangedFcn = createCallbackFcn(app, @workFileValueChanged, true);
            app.WorkingSpaceDropDown = uidropdown(app.UIFigure);
            app.WorkingSpaceDropDown.Items = {'sRGB', 'BT.2020', 'ProPhoto', 'ARRI'};
            app.WorkingSpaceDropDown.Value = 'sRGB';
            app.WorkingOETFDropDown = uidropdown(app.UIFigure);
            app.WorkingOETFDropDown.Items = {'1', '2.2', '2.4', ''};
            app.WorkingOETFDropDown.Value = '2.2';
            app.CropWorkingButton = uibutton(app.UIFigure, 'push');
            app.CropWorkingButton.ButtonPushedFcn = createCallbackFcn(app, @CropWorkingButtonPushed, true);
            app.CropWorkingButton.Text = {'Crop Working'; ''};
            app.BitDepthEditFieldLabel = uilabel(app.UIFigure);
            app.BitDepthEditFieldLabel.Text = {'Bit Depth'; ''};
            app.BitDepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitDepthEditField.Limits = [0 Inf];
            app.BitDepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitDepthEditFieldValueChanged, true);
            app.BitDepthEditField.Value = 8;
            app.ProxySizeEditFieldLabel = uilabel(app.UIFigure);
            app.ProxySizeEditFieldLabel.Text = 'Proxy Size';
            app.ProxySizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProxySizeEditField.Limits = [0 1];
            app.ProxySizeEditField.ValueChangedFcn = createCallbackFcn(app, @ProxySizeEditFieldValueChanged, true);
            app.ProxySizeEditField.Value = 0.25;
            app.SaveLUTButton = uibutton(app.UIFigure, 'push');
            app.SaveLUTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLUTButtonPushed, true);
            app.SaveLUTButton.Text = {'Save LUT'; ''};
            app.SaveBatchButton = uibutton(app.UIFigure, 'push');
            app.SaveBatchButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBatchButtonPushed, true);
            app.SaveBatchButton.Text = 'Save Batch';
            app.AllCheckBox = uicheckbox(app.UIFigure);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.AllCheckBox.Value = true;
            app.chan1CheckBox = uicheckbox(app.UIFigure);
            app.chan1CheckBox.ValueChangedFcn = createCallbackFcn(app, @chan1CheckBoxValueChanged, true);
            app.chan1CheckBox.Text = 'chan1';
            app.chan1CheckBox.Value = true;
            app.chan2CheckBox = uicheckbox(app.UIFigure);
            app.chan2CheckBox.ValueChangedFcn = createCallbackFcn(app, @chan2CheckBoxValueChanged, true);
            app.chan2CheckBox.Text = 'chan2';
            app.chan2CheckBox.Value = true;
            app.chan3CheckBox = uicheckbox(app.UIFigure);
            app.chan3CheckBox.ValueChangedFcn = createCallbackFcn(app, @chan3CheckBoxValueChanged, true);
            app.chan3CheckBox.Text = 'chan3';
            app.chan3CheckBox.Value = true;
            app.DiffusionSliderLabel = uilabel(app.UIFigure);
            app.DiffusionSliderLabel.Text = 'Diffusion';
            app.DiffusionSlider = uislider(app.UIFigure);
            app.DiffusionSlider.Limits = [0 1];
            app.DiffusionSlider.ValueChangedFcn = createCallbackFcn(app, @DiffusionSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dCoupSTplus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end